classdef DensityFitDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        FitResultTab        matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        YAxes               matlab.ui.control.UIAxes
        XAxes               matlab.ui.control.UIAxes
        GOFTab              matlab.ui.container.Tab
        GridLayout3         matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        SubROISpinner       matlab.ui.control.Spinner
        SubROISpinnerLabel  matlab.ui.control.Label
        MethodLabel         matlab.ui.control.Label
        RunSpinner          matlab.ui.control.Spinner
        RunSpinnerLabel     matlab.ui.control.Label
        RunNumberSlider     matlab.ui.control.Slider
    end

    
    properties (Access = public)
        BecExp
        RunNumber
        SubRoiNumber = 1
        RoiSize
    end
    
    methods (Access = private)
        
        function updateDensityFit(app)
            app.BecExp.DensityFit.FitDataX(1,app.RunNumber,app.SubRoiNumber).plot(app.XAxes,false);
            app.BecExp.DensityFit.FitDataY(1,app.RunNumber,app.SubRoiNumber).plot(app.YAxes,false);
            if ~isempty(app.BecExp.Roi.SubRoi)
                yxBound = app.BecExp.Roi.SubRoi(app.SubRoiNumber).YXBoundary;
                app.XAxes.XLim = [yxBound(3),yxBound(4)];
                app.YAxes.XLim = [yxBound(1),yxBound(2)];
            end
        end
    end
    
    methods (Access = public)
        
        function update(app)
            if app.BecExp.NCompletedRun <= 1
                app.RunSpinner.Limits = [1,1];
                app.RunNumberSlider.Limits = [1,1.1];
                app.RunNumber = 1;
            else
                app.RunSpinner.Limits = double([1,app.BecExp.NCompletedRun]);
                app.RunSpinner.Value = double(app.BecExp.NCompletedRun);
                app.RunNumberSlider.Limits = double([1,app.BecExp.NCompletedRun]);
                app.RunNumberSlider.Value = double(app.BecExp.NCompletedRun);
                if ~isempty(app.BecExp.Roi.SubRoi)
                    app.SubROISpinner.Limits = [1,numel(app.BecExp.Roi.SubRoi)];
                end
                if app.BecExp.NCompletedRun < 11
                    app.RunNumberSlider.MajorTicks = 1:app.BecExp.NCompletedRun;
                else
                    ticks = 0:5:app.BecExp.NCompletedRun;
                    ticks(1) = 1;
                    if ticks(end) ~= app.BecExp.NCompletedRun
                        ticks = [ticks,app.BecExp.NCompletedRun];
                    end
                    app.RunNumberSlider.MajorTicks = ticks;
                end
                app.RunNumber = app.BecExp.NCompletedRun;
                app.SubRoiNumber = 1;
            end
            app.updateDensityFit
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, becExp)
            app.BecExp = becExp;
            roiSize = becExp.Roi.CenterSize(3:4);
            yxBound = becExp.Roi.YXBoundary;
            densityLim = becExp.Ad.Unit * becExp.Ad.CLim * becExp.Acquisition.PixelSizeReal * max(roiSize) / 5;

            %% Set spinner and slider
            app.RunNumberSlider.Value = 1;
            app.RunSpinner.Value = 1;
            nRun = double(app.BecExp.NCompletedRun);
            if nRun < 1
                nRun = 1;
            end
            app.RunSpinner.Limits = [1,nRun];
            app.RunSpinner.Value = nRun;
            if nRun == 1
                app.RunNumberSlider.Limits = [1,1.1];
            else
                app.RunNumberSlider.Limits = [1,nRun];
            end
            app.RunNumberSlider.Value = nRun;
            if nRun < 11
                app.RunNumberSlider.MajorTicks = 1:nRun;
            else
                ticks = 0:5:nRun;
                ticks(1) = 1;
                if ticks(end) ~= nRun
                    ticks = [ticks,nRun];
                end
                app.RunNumberSlider.MajorTicks = ticks;
            end
            app.RunNumber = nRun;

            %% Set sub Roi plot
            if ~isempty(becExp.Roi.SubRoi)
                app.SubROISpinner.Enable = "on";
            end

            %% Initialize OD Plots
            app.XAxes.XLim = [yxBound(3),yxBound(4)];
            app.XAxes.YLim = densityLim;
            app.XAxes.XLabel.String = "$x$ position [Pixels]";
            app.XAxes.XLabel.Interpreter = "latex";
            app.XAxes.YLabel.String = "$n_{1\mathrm{D}}~[\mathrm{m}^{-1}]$";
            app.XAxes.YLabel.Interpreter = "latex";
            app.XAxes.FontSize = 14;
            app.XAxes.Box = "on";

            app.YAxes.XLim = [yxBound(1),yxBound(2)];
            app.YAxes.YLim = densityLim;
            app.YAxes.XLabel.String = "$y$ position [Pixels]";
            app.YAxes.XLabel.Interpreter = "latex";
            app.YAxes.YLabel.String = "$n_{1\mathrm{D}}~[\mathrm{m}^{-1}]$";
            app.YAxes.YLabel.Interpreter = "latex";
            app.YAxes.FontSize = 14;
            app.YAxes.Box = "on";

            %% Label
            app.MethodLabel.Text = "Method: " + app.BecExp.DensityFit.FitMethod;

            app.update
            
        end

        % Value changed function: RunSpinner
        function RunSpinnerValueChanged(app, event)
            value = app.RunSpinner.Value;
            app.RunNumber = value;
            app.RunNumberSlider.Value = value;
            app.updateDensityFit
        end

        % Value changed function: RunNumberSlider
        function RunNumberSliderValueChanged(app, event)
            value = app.RunNumberSlider.Value;
            app.RunNumber = round(value);
            app.RunNumberSlider.Value = app.RunNumber;
            app.RunSpinner.Value = app.RunNumber;
            app.updateDensityFit
        end

        % Value changed function: SubROISpinner
        function SubROISpinnerValueChanged(app, event)
            value = app.SubROISpinner.Value;
            app.SubRoiNumber = round(value);
            app.SubROISpinner.Value = app.SubRoiNumber;
            app.updateDensityFit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Density Fit';
            app.UIFigure.Tag = 'DensityFitDisplay';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.RunNumberSlider = uislider(app.Panel);
            app.RunNumberSlider.Limits = [1 1.1];
            app.RunNumberSlider.MajorTicks = [1 2];
            app.RunNumberSlider.ValueChangedFcn = createCallbackFcn(app, @RunNumberSliderValueChanged, true);
            app.RunNumberSlider.MinorTicks = [];
            app.RunNumberSlider.Value = 1;
            app.RunSpinnerLabel = uilabel(app.Panel);
            app.RunSpinnerLabel.Text = 'Run #';
            app.RunSpinner = uispinner(app.Panel);
            app.RunSpinner.Limits = [1 1];
            app.RunSpinner.RoundFractionalValues = 'on';
            app.RunSpinner.ValueChangedFcn = createCallbackFcn(app, @RunSpinnerValueChanged, true);
            app.RunSpinner.Value = 1;
            app.MethodLabel = uilabel(app.Panel);
            app.SubROISpinnerLabel = uilabel(app.Panel);
            app.SubROISpinnerLabel.Text = 'Sub ROI #';
            app.SubROISpinner = uispinner(app.Panel);
            app.SubROISpinner.ValueChangedFcn = createCallbackFcn(app, @SubROISpinnerValueChanged, true);
            app.SubROISpinner.Enable = 'off';
            app.SubROISpinner.Value = 1;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FitResultTab = uitab(app.TabGroup);
            app.FitResultTab.Title = 'Fit Result';
            app.GridLayout2 = uigridlayout(app.FitResultTab);
            app.XAxes = uiaxes(app.GridLayout2);
            title(app.XAxes, 'Density Fit in X')
            xlabel(app.XAxes, 'x position')
            ylabel(app.XAxes, 'Density')
            zlabel(app.XAxes, 'Z')
            app.YAxes = uiaxes(app.GridLayout2);
            title(app.YAxes, 'Density Fit in Y')
            xlabel(app.YAxes, 'y position')
            ylabel(app.YAxes, 'Density')
            zlabel(app.YAxes, 'Z')
            app.GOFTab = uitab(app.TabGroup);
            app.GOFTab.Title = 'GOF';
            app.GridLayout3 = uigridlayout(app.GOFTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DensityFitDisplay(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end