classdef FringeRemoval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        TabGroup             matlab.ui.container.TabGroup
        ODTab                matlab.ui.container.Tab
        GridLayout2          matlab.ui.container.GridLayout
        AfterAxes            matlab.ui.control.UIAxes
        BeforeAxes           matlab.ui.control.UIAxes
        RankTab              matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        RankAxes             matlab.ui.control.UIAxes
        Panel                matlab.ui.container.Panel
        ODmaxEditField       matlab.ui.control.NumericEditField
        ODmaxEditFieldLabel  matlab.ui.control.Label
        ODminEditField       matlab.ui.control.NumericEditField
        ODminEditFieldLabel  matlab.ui.control.Label
        MethodLabel          matlab.ui.control.Label
        RunSpinner           matlab.ui.control.Spinner
        RunSpinnerLabel      matlab.ui.control.Label
        RunNumberSlider      matlab.ui.control.Slider
    end

    
    properties (Access = public)
        OdBefore % Description
        OdAfter
        BeforeImage
        AfterImage
        RunNumber
        RoiSize
    end
    
    methods (Access = private)
        
        function updateOd(app)
            app.BeforeImage.CData = app.OdBefore(:,:,app.RunNumber);
            app.AfterImage.CData = app.OdAfter(:,:,app.RunNumber);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OdB, OdA, Rtest, Method)
            app.OdBefore = OdB;
            app.OdAfter = OdA;
            roiSize = size(OdA,1,2);

            %% Set spinner and slider
            app.RunNumberSlider.Value = 1;
            app.RunSpinner.Value = 1;
            if ismatrix(app.OdBefore)
                nRun = 1;
            else
                nRun = size(app.OdBefore,3);
            end
            app.RunSpinner.Limits = [1,nRun];
            app.RunSpinner.Value = nRun;
            if nRun == 1
                app.RunNumberSlider.Limits = [1,1.1];
            else
                app.RunNumberSlider.Limits = [1,nRun];
            end
            app.RunNumberSlider.Value = nRun;
            if nRun < 11
                app.RunNumberSlider.MajorTicks = 1:nRun;
            else
                ticks = 0:5:nRun;
                ticks(1) = 1;
                if ticks(end) ~= nRun
                    ticks = [ticks,nRun];
                end
                app.RunNumberSlider.MajorTicks = ticks;
            end
            app.RunNumber = nRun;

            %% Initialize OD Plots
            app.BeforeImage = imagesc(app.BeforeAxes,app.OdBefore(:,:,nRun));
            app.BeforeAxes.XLim = [1,roiSize(2)];
            app.BeforeAxes.YLim = [1,roiSize(1)];
            app.BeforeAxes.Colormap = jet;
            pbaspect(app.BeforeAxes,[roiSize(2)/roiSize(1),1,1]);
            app.BeforeAxes.CLim = [0,0.5];
            app.BeforeAxes.XLabel = [];
            app.BeforeAxes.YLabel = [];
            app.BeforeAxes.XTick = [];
            app.BeforeAxes.YTick = [];

            app.AfterImage = imagesc(app.AfterAxes,app.OdAfter(:,:,nRun));
            app.AfterAxes.XLim = [1,roiSize(2)];
            app.AfterAxes.YLim = [1,roiSize(1)];
            app.AfterAxes.Colormap = jet;
            pbaspect(app.AfterAxes,[roiSize(2)/roiSize(1),1,1]);
            app.AfterAxes.CLim = [0,0.5];
            app.AfterAxes.XLabel = [];
            app.AfterAxes.YLabel = [];
            app.AfterAxes.XTick = [];
            app.AfterAxes.YTick = [];

            %% Plot rank
            semilogy(app.RankAxes,abs(diag(Rtest)), 'o')
            app.RankAxes.XLabel.String = "Index";
            app.RankAxes.YLabel.String = "Contribution";

            %% Od CLim
            app.ODmaxEditField.Value = 0.5;
            app.ODminEditField.Value = 0;

            %% Label
            app.MethodLabel.Text = "Method: " + Method;
            
        end

        % Value changed function: RunSpinner
        function RunSpinnerValueChanged(app, event)
            value = app.RunSpinner.Value;
            app.RunNumber = value;
            app.RunNumberSlider.Value = value;
            app.updateOd
        end

        % Value changed function: RunNumberSlider
        function RunNumberSliderValueChanged(app, event)
            value = app.RunNumberSlider.Value;
            app.RunNumber = round(value);
            app.RunNumberSlider.Value = app.RunNumber;
            app.RunSpinner.Value = app.RunNumber;
            app.updateOd
        end

        % Value changed function: ODminEditField
        function ODminEditFieldValueChanged(app, event)
            value = app.ODminEditField.Value;
            if value < app.BeforeAxes.CLim(2)
                app.BeforeAxes.CLim(1) = value;
                app.AfterAxes.CLim(1) = value;
            else
                warning("OD CLim min value should be smaller than max value.")
                app.ODminEditField.Value = app.BeforeAxes.CLim(1);
            end
        end

        % Value changed function: ODmaxEditField
        function ODmaxEditFieldValueChanged(app, event)
            value = app.ODmaxEditField.Value;
            if value > app.BeforeAxes.CLim(1)
                app.BeforeAxes.CLim(2) = value;
                app.AfterAxes.CLim(2) = value;
            else
                warning("OD CLim max value should be larger than min value.")
                app.ODmaxEditField.Value = app.BeforeAxes.CLim(2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FringeRemoval';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.RunNumberSlider = uislider(app.Panel);
            app.RunNumberSlider.Limits = [1 1.1];
            app.RunNumberSlider.MajorTicks = [1 2];
            app.RunNumberSlider.ValueChangedFcn = createCallbackFcn(app, @RunNumberSliderValueChanged, true);
            app.RunNumberSlider.MinorTicks = [];
            app.RunNumberSlider.Value = 1;
            app.RunSpinnerLabel = uilabel(app.Panel);
            app.RunSpinnerLabel.Text = 'Run #';
            app.RunSpinner = uispinner(app.Panel);
            app.RunSpinner.Limits = [1 1];
            app.RunSpinner.RoundFractionalValues = 'on';
            app.RunSpinner.ValueChangedFcn = createCallbackFcn(app, @RunSpinnerValueChanged, true);
            app.RunSpinner.Value = 1;
            app.MethodLabel = uilabel(app.Panel);
            app.ODminEditFieldLabel = uilabel(app.Panel);
            app.ODminEditFieldLabel.Text = 'ODmin';
            app.ODminEditField = uieditfield(app.Panel, 'numeric');
            app.ODminEditField.Limits = [0 Inf];
            app.ODminEditField.ValueChangedFcn = createCallbackFcn(app, @ODminEditFieldValueChanged, true);
            app.ODmaxEditFieldLabel = uilabel(app.Panel);
            app.ODmaxEditFieldLabel.Text = 'ODmax';
            app.ODmaxEditField = uieditfield(app.Panel, 'numeric');
            app.ODmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ODmaxEditFieldValueChanged, true);
            app.ODmaxEditField.Value = 4;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ODTab = uitab(app.TabGroup);
            app.ODTab.Title = 'OD';
            app.GridLayout2 = uigridlayout(app.ODTab);
            app.BeforeAxes = uiaxes(app.GridLayout2);
            title(app.BeforeAxes, 'OD Before Fringe Removal')
            xlabel(app.BeforeAxes, 'X')
            ylabel(app.BeforeAxes, 'Y')
            zlabel(app.BeforeAxes, 'Z')
            app.AfterAxes = uiaxes(app.GridLayout2);
            title(app.AfterAxes, 'OD After Fringe Removal')
            xlabel(app.AfterAxes, 'X')
            ylabel(app.AfterAxes, 'Y')
            zlabel(app.AfterAxes, 'Z')
            app.RankTab = uitab(app.TabGroup);
            app.RankTab.Title = 'Rank';
            app.GridLayout3 = uigridlayout(app.RankTab);
            app.RankAxes = uiaxes(app.GridLayout3);
            title(app.RankAxes, 'Fringe Removal Rank Contribution for Tolerance Check')
            xlabel(app.RankAxes, 'X')
            ylabel(app.RankAxes, 'Y')
            zlabel(app.RankAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FringeRemoval(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end