classdef ScopeControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScopeControlPenelUIFigure   matlab.ui.Figure
        Panel_2                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        TriggerModeDropDown         matlab.ui.control.DropDown
        TriggerModeDropDownLabel    matlab.ui.control.Label
        TriggerLevelEditField       matlab.ui.control.NumericEditField
        TriggerLevelEditFieldLabel  matlab.ui.control.Label
        DurationEditField           matlab.ui.control.NumericEditField
        DurationEditFieldLabel      matlab.ui.control.Label
        TriggerSlopeDropDown        matlab.ui.control.DropDown
        TriggerSlopeDropDownLabel   matlab.ui.control.Label
        TriggerSourceDropDown       matlab.ui.control.DropDown
        TriggerSourceDropDownLabel  matlab.ui.control.Label
        NSampleEditField            matlab.ui.control.NumericEditField
        NSampleEditFieldLabel       matlab.ui.control.Label
        SetButton                   matlab.ui.control.Button
        DisconnectButton            matlab.ui.control.Button
        ChannelsPanel               matlab.ui.container.Panel
        ChannelGrid                 matlab.ui.container.GridLayout
        ReadButton                  matlab.ui.control.Button
        TriggerButton               matlab.ui.control.Button
        MaxSamplingRateLabel        matlab.ui.control.Label
        ModelLabel                  matlab.ui.control.Label
        ManufacturerLabel           matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        SrcApp HardwareControlPanel
        Scope 
        Name string
    end
    
    properties (Access = private)
        ScopeSetting table
        DisabledChannelProperty
        ScopeProperty
    end

    properties (Constant)
        ChannelColumn = 5
        ChannelProperty = ["IsEnabled","VerticalCoupling","VerticalOffset","VerticalRange"]
    end
    
    methods (Access = private)
        
        function disableProperty(app)
            dP = app.Scope.DisabledProperty;
            nc = app.Scope.NChannel;
            cc = app.ChannelColumn;
            appProp = string(properties(app));
            for ii = 1:numel(dP)
                targetAppProp = appProp(contains(appProp,dP(ii)));
                for jj = 1:numel(targetAppProp)
                    try
                        app.(targetAppProp(jj)).Enable = "off";
                    catch
                    end
                end
            end
            for ii = app.DisabledChannelProperty
                idx = (ii+cc):cc:cc*(nc+1);
                for jj = idx
                    app.ChannelGrid.Children(jj).Enable = "off";
                end
            end
        end

        function switchValueChanged(app,src,~,cNumber)
            app.Scope.IsEnabled(cNumber) = src.Value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).IsEnabled{1}(cNumber) ...
                = src.Value;
            app.toggleChannel(cNumber)
            app.updateSrc;
        end
        
        function toggleChannel(app,channelIdx)
            cc = app.ChannelColumn;
            dcp = app.DisabledChannelProperty;
            firstButton = 3;
            firstButtonIdx = cc * channelIdx + firstButton;
            if string(app.ChannelGrid.Children(firstButtonIdx).Enable) == "on"
                for ii = firstButtonIdx:(firstButtonIdx + cc - firstButton)
                    app.ChannelGrid.Children(ii).Enable = "off";
                end
            else
                for ii = firstButtonIdx:(firstButtonIdx + cc - firstButton)
                    if ~ismember((ii - cc * channelIdx),dcp)
                        app.ChannelGrid.Children(ii).Enable = "on";
                    end
                end
            end 
        end
        
        function couplingValueChanged(app,src,~,cNumber)
            app.Scope.VerticalCoupling(cNumber) = src.Value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).VerticalCoupling{1}(cNumber) ...
                = src.Value;
            app.updateSrc;
        end

        function offsetValueChanged(app,src,~,cNumber)
            app.Scope.VerticalOffset(cNumber) = src.Value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).VerticalOffset{1}(cNumber) ...
                = src.Value;
            app.updateSrc;
        end

        function rangeValueChanged(app,src,~,cNumber)
            app.Scope.VerticalRange(cNumber) = src.Value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).VerticalRange{1}(cNumber) ...
                = src.Value;
            app.updateSrc;
        end

        function saveScopeSetting(app)
            if isempty(app.SrcApp)
                ScopeSetting = app.ScopeSetting;
                save(which("ScopeSetting.mat"),"ScopeSetting");
            else
                app.updateSrc;
            end    
        end

        function updateSrc(app)
            if ~isempty(app.SrcApp)
                app.SrcApp.ScopeSetting(app.ScopeSetting.Name == app.Name,:) = ...
                    app.ScopeSetting(app.ScopeSetting.Name == app.Name,:);
            end
        end
    end
    
    methods (Access = public)
        
        function disconnect(app)
            if ~isempty(app.Scope)
                app.Scope.close
            end
            delete(app)
        end
        
        function read(app)
            app.Scope.read
            app.Scope.plot(app.UIAxes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, srcApp)
            if nargin == 3
                app.SrcApp = srcApp;
                app.ScopeSetting = srcApp.ScopeSetting;
                app.DisconnectButton.Enable = 'off';
            elseif isfile("ScopeSetting.mat")
                app.ScopeSetting = loadVar("ScopeSetting.mat","ScopeSetting");
            end

            %% Load setting
            app.Scope = getScope(name,false);
            % app.Scope.connect
            app.Name = app.Scope.Name;
            maxSr = app.Scope.SamplingRateMax;
            app.MaxSamplingRateLabel.Text = app.MaxSamplingRateLabel.Text + string(maxSr);
            setting = app.ScopeSetting(app.ScopeSetting.Name == name,:);
            if isempty(setting)
                % Assign default settings to the scope
                dm = (app.Scope.Manufacturer + app.Scope.Model);
                t = app.ScopeSetting([app.ScopeSetting.DeviceModel] == dm,:);
                if ~isempty(t)
                    setting = t(1,:);
                else
                    warning("Can not find saved settings for " + dm + ". Will assign saved settings from another device.")
                    setting = app.ScopeSetting(1,:);
                    setting.DeviceModel = dm;
                end
                setting.Name = app.Scope.Name;
                app.ScopeSetting(end+1,:) = setting;
                if ~isempty(app.SrcApp)
                    app.SrcApp.ScopeSetting = app.ScopeSetting;
                end
            end
            app.Scope.Duration = setting.Duration;
            app.Scope.NSample = setting.NSample;
            app.Scope.TriggerMode = setting.TriggerMode;
            app.Scope.TriggerSource = setting.TriggerSource;
            app.Scope.TriggerSlope = setting.TriggerSlope;
            app.Scope.TriggerLevel = setting.TriggerLevel;
            app.Scope.IsEnabled = setting.IsEnabled{1};
            app.Scope.VerticalCoupling = setting.VerticalCoupling{1};
            app.Scope.VerticalOffset = setting.VerticalOffset{1};
            app.Scope.VerticalRange = setting.VerticalRange{1};
            app.DisabledChannelProperty = 1 + find(ismember(app.ChannelProperty,app.Scope.DisabledProperty));
            % app.Scope.set

            %% Change labels
            app.NameLabel.Text = app.NameLabel.Text + app.Name;
            app.ManufacturerLabel.Text = app.ManufacturerLabel.Text + app.Scope.Manufacturer;
            app.ModelLabel.Text = app.ModelLabel.Text + app.Scope.Model;
            app.DurationEditField.Limits = [0,maxSr / app.Scope.NSample];
            app.DurationEditField.Value = app.Scope.Duration;
            app.NSampleEditField.Limits = [1,maxSr * app.Scope.Duration];
            app.NSampleEditField.Value = app.Scope.NSample;
            app.TriggerModeDropDown.Value = app.Scope.TriggerMode;
            app.TriggerSourceDropDown.Items = ["CH" + (1:app.Scope.NChannel),"External"];
            app.TriggerSourceDropDown.Value = app.Scope.TriggerSource;
            if app.TriggerSourceDropDown.Value ~= "Software"
                app.TriggerButton.Enable = "off";
            else
                app.TriggerButton.Enable = "on";
            end
            app.TriggerSlopeDropDown.Value = app.Scope.TriggerSlope;
            app.TriggerLevelEditField.Value = app.Scope.TriggerLevel;
            app.ChannelGrid.RowHeight{end + 1} = 25;
            uilabel(app.ChannelGrid,"Text","");
            uilabel(app.ChannelGrid,"Text","Enable");
            uilabel(app.ChannelGrid,"Text","Coupling");
            uilabel(app.ChannelGrid,"Text","Offset");
            uilabel(app.ChannelGrid,"Text","Range");
            
            for ii = 1:app.Scope.NChannel
                app.ChannelGrid.RowHeight{end + 1} = 25;
                uilabel(app.ChannelGrid,"Text","Ch" + num2str(ii));
                uiswitch(app.ChannelGrid, 'slider','ItemsData',[0,1],'Value',app.Scope.IsEnabled(ii),...
                    'ValueChangedFcn',{@app.switchValueChanged,ii});
                uidropdown(app.ChannelGrid,"Items",["DC","AC"],'Value',app.Scope.VerticalCoupling(ii),...
                    'ValueChangedFcn',{@app.couplingValueChanged,ii});
                uieditfield(app.ChannelGrid,"numeric",'Value',app.Scope.VerticalOffset(ii),...
                    'ValueChangedFcn',{@app.offsetValueChanged,ii});
                uieditfield(app.ChannelGrid,"numeric",'Value',app.Scope.VerticalRange(ii),...
                    'ValueChangedFcn',{@app.rangeValueChanged,ii});
                if ~app.Scope.IsEnabled(ii)
                    app.toggleChannel(ii)
                end
            end

            %% Render the plot
            fsz = 12/0.8;
            lw = 2;
            app.UIAxes.FontSize = fsz;
            app.UIAxes.LineWidth = lw;
            app.UIAxes.Box = "on";

            %% Diable properties
            app.disableProperty;
        end

        % Close request function: ScopeControlPenelUIFigure
        function ScopeControlPenelUIFigureCloseRequest(app, event)
            app.saveScopeSetting;
            if ~isempty(app.SrcApp)
                app.ScopeControlPenelUIFigure.Visible = 'off';
            else
                app.disconnect
            end
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            value = app.DurationEditField.Value;
            app.Scope.Duration = value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).Duration = value;
            app.updateSrc;
        end

        % Value changed function: NSampleEditField
        function NSampleEditFieldValueChanged(app, event)
            value = app.NSampleEditField.Value;
            app.Scope.NSample = value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).NSample = value;
            app.updateSrc;
        end

        % Value changed function: TriggerModeDropDown
        function TriggerModeDropDownValueChanged(app, event)
            value = app.TriggerModeDropDown.Value;
            app.Scope.TriggerMode = value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).TriggerMode = value;
            app.updateSrc;
        end

        % Value changed function: TriggerSourceDropDown
        function TriggerSourceDropDownValueChanged(app, event)
            value = app.TriggerSourceDropDown.Value;
            app.Scope.TriggerSource = value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).TriggerSource = value;
            app.updateSrc;
        end

        % Value changed function: TriggerSlopeDropDown
        function TriggerSlopeDropDownValueChanged(app, event)
            value = app.TriggerSlopeDropDown.Value;
            app.Scope.TriggerSlope = value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).TriggerSlope = value;
            app.updateSrc;
        end

        % Value changed function: TriggerLevelEditField
        function TriggerLevelEditFieldValueChanged(app, event)
            value = app.TriggerLevelEditField.Value;
            app.Scope.TriggerLevel = value;
            app.ScopeSetting(app.ScopeSetting.Name == app.Name,:).TriggerLevel = value;
            app.updateSrc;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.Scope.set
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            app.read
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.saveScopeSetting
            app.disconnect
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScopeControlPenelUIFigure = uifigure('Visible', 'off');
            app.ScopeControlPenelUIFigure.Name = 'MATLAB App';
            app.ScopeControlPenelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScopeControlPenelUIFigureCloseRequest, true);
            app.Panel = uipanel(app.ScopeControlPenelUIFigure);
            app.NameLabel = uilabel(app.Panel);
            app.NameLabel.Text = 'Name: ';
            app.ManufacturerLabel = uilabel(app.Panel);
            app.ManufacturerLabel.Text = 'Manufacturer: ';
            app.ModelLabel = uilabel(app.Panel);
            app.ModelLabel.Text = 'Model: ';
            app.MaxSamplingRateLabel = uilabel(app.Panel);
            app.MaxSamplingRateLabel.Text = 'Max Sampling Rate: ';
            app.TriggerButton = uibutton(app.Panel, 'push');
            app.TriggerButton.Text = 'Trigger';
            app.ReadButton = uibutton(app.Panel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.ChannelsPanel = uipanel(app.Panel);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelGrid = uigridlayout(app.ChannelsPanel);
            app.DisconnectButton = uibutton(app.Panel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SetButton = uibutton(app.Panel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.NSampleEditFieldLabel = uilabel(app.Panel);
            app.NSampleEditFieldLabel.Text = 'NSample: ';
            app.NSampleEditField = uieditfield(app.Panel, 'numeric');
            app.NSampleEditField.LowerLimitInclusive = 'off';
            app.NSampleEditField.Limits = [0 Inf];
            app.NSampleEditField.RoundFractionalValues = 'on';
            app.NSampleEditField.ValueChangedFcn = createCallbackFcn(app, @NSampleEditFieldValueChanged, true);
            app.NSampleEditField.Value = 1000;
            app.TriggerSourceDropDownLabel = uilabel(app.Panel);
            app.TriggerSourceDropDownLabel.Text = 'Trigger Source:';
            app.TriggerSourceDropDown = uidropdown(app.Panel);
            app.TriggerSourceDropDown.Items = {'External', 'Software', 'Immediate'};
            app.TriggerSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerSourceDropDownValueChanged, true);
            app.TriggerSourceDropDown.Value = 'External';
            app.TriggerSlopeDropDownLabel = uilabel(app.Panel);
            app.TriggerSlopeDropDownLabel.Text = 'Trigger Slope:';
            app.TriggerSlopeDropDown = uidropdown(app.Panel);
            app.TriggerSlopeDropDown.Items = {'Rise', 'Fall'};
            app.TriggerSlopeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerSlopeDropDownValueChanged, true);
            app.TriggerSlopeDropDown.Value = 'Rise';
            app.DurationEditFieldLabel = uilabel(app.Panel);
            app.DurationEditFieldLabel.Text = 'Duration:';
            app.DurationEditField = uieditfield(app.Panel, 'numeric');
            app.DurationEditField.LowerLimitInclusive = 'off';
            app.DurationEditField.Limits = [0 Inf];
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.DurationEditField.Value = 1;
            app.TriggerLevelEditFieldLabel = uilabel(app.Panel);
            app.TriggerLevelEditFieldLabel.Text = 'Trigger Level:';
            app.TriggerLevelEditField = uieditfield(app.Panel, 'numeric');
            app.TriggerLevelEditField.LowerLimitInclusive = 'off';
            app.TriggerLevelEditField.Limits = [0 Inf];
            app.TriggerLevelEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerLevelEditFieldValueChanged, true);
            app.TriggerLevelEditField.Value = 0.1;
            app.TriggerModeDropDownLabel = uilabel(app.Panel);
            app.TriggerModeDropDownLabel.Text = 'Trigger Mode:';
            app.TriggerModeDropDown = uidropdown(app.Panel);
            app.TriggerModeDropDown.Items = {'Normal', 'Auto', 'Software'};
            app.TriggerModeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerModeDropDownValueChanged, true);
            app.TriggerModeDropDown.Value = 'Normal';
            app.Panel_2 = uipanel(app.ScopeControlPenelUIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.ScopeControlPenelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScopeControlPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScopeControlPenelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScopeControlPenelUIFigure)
        end
    end
end